classdef DIGRAFICAS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        bucle                          matlab.ui.control.Label
        boton_cerrado_continuar        matlab.ui.control.Button
        Advertencia_cerrar_excel2      matlab.ui.control.Label
        advertencia_cerrar_Excel1      matlab.ui.control.Label
        advertencia_incidencia         matlab.ui.control.Label
        Num_arista                     matlab.ui.control.Label
        RegresaralmenuprincipalButton  matlab.ui.control.Button
        MatrizadyacenciaButton         matlab.ui.control.Button
        MatrizIncidenciaButton         matlab.ui.control.Button
        IngresarArista                 matlab.ui.control.Button
        AlLabel                        matlab.ui.control.Label
        DELLabel                       matlab.ui.control.Label
        VerticeFinal                   matlab.ui.control.Spinner
        VerticeSpinnerLabel            matlab.ui.control.Label
        VerticeInicial                 matlab.ui.control.Spinner
        VerticeLabel                   matlab.ui.control.Label
        IngreselaaristaLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        G; % grafo
        h;%el plot
        i=1;%contador
        ID_aristas=[];%%este vector almacena el ID de cada arista
        cantidad_vertices;%esta variable almacena la cantidad de aristas de la grafica
        numero_arista=2;%indica que arista se va a ingresar 

    end
    
    methods (Access = private)
        
         function  etiquetas(app)
            app.ID_aristas(app.i)=app.i;
            app.i=app.i+1;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, vertices)
            app.cantidad_vertices=vertices;%se establece la cantidad de vertices
            limites=[];%este vector almacenara los limites del spinner
            limites(1)=1;%se establece que el spiner empezara en 1
            limites(2)=vertices;%% el spiner acabara en la cantidad de vertices
            app.G=digraph;%% se construye la digrafica
            app.G=app.G.addnode(vertices);%se construye la grafica la cantidad de vertices      % metodo constructr
            app.VerticeInicial.Limits=limites;
            app.VerticeFinal.Limits=limites;
            app.h=plot(app.G);%se plotea la grafica
        end

        % Button pushed function: IngresarArista
        function IngresarAristaButtonPushed(app, event)
            app.advertencia_incidencia.Text='';
            app.G=app.G.addedge(app.VerticeInicial.Value,app.VerticeFinal.Value);
            etiquetas(app);
            app.h=plot(app.G);
            labeledge(app.h,app.ID_aristas,app.ID_aristas);
            app.Num_arista.Text=char(""+app.numero_arista);
            app.numero_arista=app.numero_arista+1;
        end

        % Button pushed function: MatrizIncidenciaButton
        function MatrizIncidenciaButtonPushed(app, event)
                
                vertices=app.G.numnodes;%se obtienen el numero de vertices
                aristas=app.G.numedges;% se obtiene el numero de aristas
                variable_names={};% en esta se almacenaran los nombres de las columnas
                matriz={};%se almacenara la matria
                inicio=0;
                final=0;
                if aristas==0
                    app.advertencia_incidencia.Text='Ingresa al menos una arista para continuar!!';
                    return;
                end
                system('cerrar_excel.bat');

                for index=1:(aristas+1)%eate for llena la parte superior de la hoja de excel
                    if index==1
                        variable_names(1)={"Aristas/Vertices"};
                    else
                        variable_names(index)={"e"+(index-1)};
                    end    
                end%llave final del for

                for index=1:vertices% este for identidica el vertice
                    matriz(index,1)={index};
                end%final del for

                for columna=1:aristas%for externo
                    [inicio,final]=app.G.findedge(columna);
                    for fila=1:vertices
                        if fila==inicio && fila==final
                            matriz(fila,columna+1)={2};
                        else
                            if fila==inicio 
                                matriz(fila,columna+1)={1};
                            elseif fila==final                    
                                matriz(fila,columna+1)={-1};
                            else
                                 matriz(fila,columna+1)={0};
                            end
                        end
                    end
                end%final for externo

            ruta_archivo = 'digrafica_matriz_incidencia.xlsx';
            comando = ['del ', ruta_archivo];   
            system(comando);
            str=string(variable_names);
            T=cell2table(matriz,"VariableNames",str);
            writetable(T,'digrafica_matriz_incidencia.xlsx');
            
            fid = fopen('abrir_archivo.bat', 'w');
            fprintf(fid, 'start "" "digrafica_matriz_incidencia.xlsx"\n');
            fclose(fid);

            % Ejecutar el archivo .bat
            system('abrir_archivo.bat');

            % Eliminar el archivo .bat
            system('del abrir_archivo.bat');

            tablas(str,T);
        end

        % Button pushed function: MatrizadyacenciaButton
        function MatrizadyacenciaButtonPushed(app, event)
            num_vertices=app.cantidad_vertices;% esta variable almacena la cantidad de vertices de la grafica
            variable_names={};
            matriz={};
            delete(tablas);
           
            system('cerrar_excel.bat');
            

            for index=1:(num_vertices+1)%eate for llena la parte superior de la hoja de excel
                    if index==1
                        variable_names(1)={"Vertices"};
                    else
                        variable_names(index)={""+(index-1)};
                    end    
             end%llave final del for
             
            
             for index=1:num_vertices% este for identidica el vertice
                    matriz(index,1)={index};
             end%final del for


             for filas=1:num_vertices
                for columnas=1:num_vertices
                    valor=findedge(app.G,filas,columnas);
                    if valor==0
                        matriz(filas,columnas+1)={0};
                    else
                        matriz(filas,columnas+1)={1};
                    end
                end
             end
            
            ruta_archivo = 'digrafica_matriz_adyacencia.xlsx';
            comando = ['del ', ruta_archivo];   
            system(comando);
            str=string(variable_names);
            T=cell2table(matriz,"VariableNames",str);
            writetable(T,'digrafica_matriz_adyacencia.xlsx');
            % Crear el archivo .bat
            fid = fopen('abrir_archivo.bat', 'w');
            fprintf(fid, 'start "" "digrafica_matriz_adyacencia.xlsx"\n');
            fclose(fid);
            
            % Ejecutar el archivo .bat
            system('abrir_archivo.bat');
            
            % Eliminar el archivo .bat
            system('del abrir_archivo.bat');
            tablas(str,T);


        end

        % Button pushed function: RegresaralmenuprincipalButton
        function RegresaralmenuprincipalButtonPushed(app, event)
            system('cerrar_excel.bat');
            
            ruta_archivo = 'digrafica_matriz_incidencia.xlsx';
            comando = ['del ', ruta_archivo];   
            system(comando);
            
            ruta_archivo = 'digrafica_matriz_adyacencia.xlsx';
            comando = ['del ', ruta_archivo];   
            system(comando);

            app2();
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            
        end

        % Button pushed function: boton_cerrado_continuar
        function boton_cerrado_continuarPushed(app, event)
            app.Advertencia_cerrar_excel2.Visible='off';
            app.advertencia_cerrar_Excel1.Visible='off';
            app.boton_cerrado_continuar.Visible='off';
            app.advertencia_incidencia.Visible='on';
            app.MatrizIncidenciaButton.Visible='on';
            app.MatrizadyacenciaButton.Visible='on';
            app.bucle.Visible='on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.IngreselaaristaLabel = uilabel(app.UIFigure);
            app.IngreselaaristaLabel.Text = 'Ingrese la arista';
            app.VerticeLabel = uilabel(app.UIFigure);
            app.VerticeLabel.Text = 'Vertice';
            app.VerticeInicial = uispinner(app.UIFigure);
            app.VerticeSpinnerLabel = uilabel(app.UIFigure);
            app.VerticeSpinnerLabel.Text = 'Vertice';
            app.VerticeFinal = uispinner(app.UIFigure);
            app.DELLabel = uilabel(app.UIFigure);
            app.DELLabel.Text = 'DEL';
            app.AlLabel = uilabel(app.UIFigure);
            app.AlLabel.Text = 'Al';
            app.IngresarArista = uibutton(app.UIFigure, 'push');
            app.IngresarArista.ButtonPushedFcn = createCallbackFcn(app, @IngresarAristaButtonPushed, true);
            app.IngresarArista.Text = 'Ingresar la Arista';
            app.MatrizIncidenciaButton = uibutton(app.UIFigure, 'push');
            app.MatrizIncidenciaButton.ButtonPushedFcn = createCallbackFcn(app, @MatrizIncidenciaButtonPushed, true);
            app.MatrizIncidenciaButton.Visible = 'off';
            app.MatrizIncidenciaButton.Text = 'Matriz Incidencia';
            app.MatrizadyacenciaButton = uibutton(app.UIFigure, 'push');
            app.MatrizadyacenciaButton.ButtonPushedFcn = createCallbackFcn(app, @MatrizadyacenciaButtonPushed, true);
            app.MatrizadyacenciaButton.Visible = 'off';
            app.MatrizadyacenciaButton.Text = 'Matriz adyacencia';
            app.RegresaralmenuprincipalButton = uibutton(app.UIFigure, 'push');
            app.RegresaralmenuprincipalButton.ButtonPushedFcn = createCallbackFcn(app, @RegresaralmenuprincipalButtonPushed, true);
            app.RegresaralmenuprincipalButton.Text = 'Regresar al menu principal';
            app.Num_arista = uilabel(app.UIFigure);
            app.Num_arista.Text = '1';
            app.advertencia_incidencia = uilabel(app.UIFigure);
            app.advertencia_incidencia.Visible = 'off';
            app.advertencia_incidencia.Text = 'Debes ingresar al menos una arista para esta opcion';
            app.advertencia_cerrar_Excel1 = uilabel(app.UIFigure);
            app.advertencia_cerrar_Excel1.Text = 'Advertencia, el programa cierra todos los documentos de excel en ejecucion para su correcto funcionamiento';
            app.Advertencia_cerrar_excel2 = uilabel(app.UIFigure);
            app.Advertencia_cerrar_excel2.Text = 'Guarde su trabajo para continuar';
            app.boton_cerrado_continuar = uibutton(app.UIFigure, 'push');
            app.boton_cerrado_continuar.ButtonPushedFcn = createCallbackFcn(app, @boton_cerrado_continuarPushed, true);
            app.boton_cerrado_continuar.Text = 'Los he cerrado y deseo continuar';
            app.bucle = uilabel(app.UIFigure);
            app.bucle.Visible = 'off';
            app.bucle.Text = 'Un 2 indica bucles';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DIGRAFICAS(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end